classdef phxex_excavator_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        PistonlengthsLabel           matlab.ui.control.Label
        TiltangleLabel               matlab.ui.control.Label
        VerticaldisplacementLabel    matlab.ui.control.Label
        HorizontaldisplacementLabel  matlab.ui.control.Label
        Gauge3                       matlab.ui.control.LinearGauge
        Gauge2                       matlab.ui.control.LinearGauge
        Gauge1                       matlab.ui.control.LinearGauge
        STARTButton                  matlab.ui.control.StateButton
        Slider3                      matlab.ui.control.Slider
        Slider2                      matlab.ui.control.Slider
        Slider1                      matlab.ui.control.Slider
        ScenePanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        pos0
        ang0
        bucket
        piston
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Figure setup
            ax = cla(app.UIAxes);
            phx.extra.Viewer(ax, "DefaultCameraPosition", [-5, 11, 5], "DefaultCameraTarget", [0 0 1], "RestrictedNavigation", true);

            % Resources directory
            resdir = fullfile(fileparts(mfilename("fullpath")), "res", " ");

            % Physical model
            g = phx.Body(ax, "Type", "static", "Position", [0 0 -0.05], "Shape", {"Box", "Size", [10 6 0.1], "Color", [1 1 1]});
            a = phx.Body(ax, "Position", [-3 0 1.25], "EulerAngles", [0 -pi/2 0], "Shape", {"Box", "Size", [2 0.2 0.2], "Color", [1 1 0.8]}, "Collisions", false);
            b = phx.Body(ax, "Position", [-1.75 0 2.5], "EulerAngles", [0 0 0], "Shape", {"Box", "Size", [2 0.2 0.2], "Color", [1 0.8 1]}, "Collisions", false);
            app.bucket = phx.Body(ax, "Type", "kinematic", "Position", [-0.07 0 2.9], "EulerAngles", [0 -1.5 0], "Shape", {resdir+"Bucket.stl", "Scale", 0.01, "Centered", true, "Color", 0.4, "Envelope", "concave"});

            % Draw arms from the body centres to their joint points
            line(a.Graphics, [-1.25 1.25 NaN 0 0], [0 0 NaN 0 0], [0 0 NaN 0.25 -0.25], "LineWidth", 2, "Color", a.Color, "Marker", ".", "MarkerSize", 20);
            line(b.Graphics, [-1.25 1.25 NaN 0 0], [0 0 NaN 0 0], [0 0 NaN 0.35 -0.25], "LineWidth", 2, "Color", b.Color, "Marker", ".", "MarkerSize", 20);

            % Store initial position and angles of the kinematic body
            app.pos0 = app.bucket.Position;
            app.ang0 = app.bucket.EulerAngles;

            % Create static bodies for the environment
            phx.Body(ax, "Type", "static", "Position", [3 0 0.6], "Shape", {"Box", "Size", [0.1 5 1.2], "Color", [1 1 1]});
            phx.Body(ax, "Type", "static", "Position", [2 2.5 0.6], "Shape", {"Box", "Size", [2 0.1 1.2], "Color", [1 1 1]});
            phx.Body(ax, "Type", "static", "Position", [2 -2.5 0.6], "Shape", {"Box", "Size", [2 0.1 1.2], "Color", [1 1 1]});

            % Randomly generate rocks in the scene
            for i = 1:50
                phx.Body(ax, "Position", [rand*2 rand*4-2 0.2+i*0.04], "Shape", {"Rock", "Radius", 0.3, "Color", (rand + [1 0.5 0])/2});
            end

            % Add joints and constraints to the bodies
            phx.RevoluteJoint(g, a, "AxisA", [0 1 0], "AxisB", [0 1 0], "PointA", [-3 0 0.05], "PointB", [-1.25 0 0]);
            phx.RevoluteJoint(a, b, "AxisA", [0 1 0], "AxisB", [0 1 0], "PointA", [1.25 0 0], "PointB", [-1.25 0 0]);
            phx.RevoluteJoint(b, app.bucket, "AxisA", [0 1 0], "AxisB", [0 1 0], "PointA", [1.25 0 0], "PointB", [-0.4 0 0.43]);

            % Initialize spring parameters
            k = 0; % Spring stiffness
            d = 0; % Damping coefficient

            % Create springs connecting the bodies
            app.piston = phx.Spring(g, a, "PointA", [-4.3 0 0.05], "PointB", [0 0 0.25], "FreeLength", 1.65, "Stiffness", k, "Damping", d);
            app.piston(2) = phx.Spring(a, b, "PointA", [0 0 -0.25], "PointB", [0 0 -0.25], "FreeLength", 1.50, "Stiffness", k, "Damping", d);
            app.piston(3) = phx.Spring(b, app.bucket, "PointA", [0 0 0.35], "PointB", [0 0 0.43], "FreeLength", 1.25, "Stiffness", k, "Damping", d);

            app.Slider1.Value = app.pos0(1);
            app.Slider2.Value = app.pos0(3);
            app.Slider3.Value = -1.5; %app.ang0(2);
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            app.STARTButton.Text = "STOP";
            sim = phx.Simulation(app.UIAxes, "EngineSettings", phx.engine.BulletSettings("Solver", "nncg"));
            i = 1;
    
            % Main simulation loop
            while app.STARTButton.Value
                pos = app.bucket.Position;
                ang = app.bucket.EulerAngles;
        
                % Update position and angles of the kinematic body
                app.bucket.Position = pos + (app.pos0 - pos)*0.05;
                app.bucket.EulerAngles = ang + (app.ang0 - ang)*0.05;
                sim.step(0.01);

                % Update the figure every 5 iterations
                i = i + 1;
                if mod(i, 5) == 0
                    % Update gauges
                    app.Gauge1.Value = app.piston(1).Length + app.piston(1).FreeLength;
                    app.Gauge2.Value = app.piston(2).Length + app.piston(2).FreeLength;
                    app.Gauge3.Value = app.piston(3).Length + app.piston(3).FreeLength;
                    drawnow;
                end
            end
        
            % Clean up simulation and figure
            delete(sim);
            app.STARTButton.Text = "START";
        end

        % Value changing function: Slider1
        function Slider1ValueChanging(app, event)
            app.pos0(1) = -event.Value;
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            app.pos0(3) = event.Value;
        end

        % Value changing function: Slider3
        function Slider3ValueChanging(app, event)
            app.ang0(2) = event.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ScenePanel = uipanel(app.GridLayout);
            app.ScenePanel.Title = 'Scene';
            app.GridLayout2 = uigridlayout(app.ScenePanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Slider1 = uislider(app.GridLayout);
            app.Slider1.Limits = [-1.5 2];
            app.Slider1.ValueChangingFcn = createCallbackFcn(app, @Slider1ValueChanging, true);
            app.Slider2 = uislider(app.GridLayout);
            app.Slider2.Limits = [0.5 3];
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider2.Value = 0.5;
            app.Slider3 = uislider(app.GridLayout);
            app.Slider3.Limits = [-1.5 0.5];
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.STARTButton = uibutton(app.GridLayout, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.Gauge1 = uigauge(app.GridLayout, 'linear');
            app.Gauge1.Limits = [0 3];
            app.Gauge2 = uigauge(app.GridLayout, 'linear');
            app.Gauge2.Limits = [0 3];
            app.Gauge3 = uigauge(app.GridLayout, 'linear');
            app.Gauge3.Limits = [0 3];
            app.HorizontaldisplacementLabel = uilabel(app.GridLayout);
            app.HorizontaldisplacementLabel.Text = 'Horizontal displacement';
            app.VerticaldisplacementLabel = uilabel(app.GridLayout);
            app.VerticaldisplacementLabel.Text = 'Vertical displacement';
            app.TiltangleLabel = uilabel(app.GridLayout);
            app.TiltangleLabel.Text = 'Tilt angle';
            app.PistonlengthsLabel = uilabel(app.GridLayout);
            app.PistonlengthsLabel.Text = 'Piston lengths';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phxex_excavator_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end