classdef MaterialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        SpecularExponentSlider       matlab.ui.control.Slider
        SpecularExponentSliderLabel  matlab.ui.control.Label
        SpecularStrengthSlider       matlab.ui.control.Slider
        SpecularStrengthSliderLabel  matlab.ui.control.Label
        DiffuseStrengthSlider        matlab.ui.control.Slider
        DiffuseStrengthSliderLabel   matlab.ui.control.Label
        AmbientStrengthSlider        matlab.ui.control.Slider
        AmbientStrengthSliderLabel   matlab.ui.control.Label
        CancelButton                 matlab.ui.control.Button
        ColorColorPicker             matlab.ui.control.ColorPicker
        ColorColorPickerLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Primitive
        Backup
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Primitive)
            app.Primitive = Primitive;

            app.Backup.Color = Primitive.Color;
            app.Backup.AmbientStrength = Primitive.AmbientStrength;
            app.Backup.DiffuseStrength = Primitive.DiffuseStrength;
            app.Backup.SpecularStrength = Primitive.SpecularStrength;
            app.Backup.SpecularExponent = Primitive.SpecularExponent;

            app.ColorColorPicker.Value = Primitive.Color;
            app.AmbientStrengthSlider.Value = Primitive.AmbientStrength;
            app.DiffuseStrengthSlider.Value = Primitive.DiffuseStrength;
            app.SpecularStrengthSlider.Value = Primitive.SpecularStrength;
            app.SpecularExponentSlider.Value = Primitive.SpecularExponent;

            app.UIFigure.Position(1:2) = get(0, "PointerLocation") - app.UIFigure.Position(3:4) - [10 0];
        end

        % Callback function: ColorColorPicker
        function ColorColorPickerValueChanged(app, event)
            app.Primitive.Color = app.ColorColorPicker.Value;
        end

        % Value changing function: AmbientStrengthSlider
        function AmbientStrengthSpinnerValueChanged(app, event)
            app.Primitive.AmbientStrength = event.Value;
        end

        % Value changing function: DiffuseStrengthSlider
        function DiffuseStrengthSpinnerValueChanged(app, event)
            app.Primitive.DiffuseStrength = event.Value;
        end

        % Value changing function: SpecularStrengthSlider
        function SpecularStrengthSpinnerValueChanged(app, event)
            app.Primitive.SpecularStrength = event.Value;
        end

        % Value changing function: SpecularExponentSlider
        function SpecularExponentSpinnerValueChanged(app, event)
            app.Primitive.SpecularExponent = event.Value;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Primitive.Color = app.Backup.Color;
            app.Primitive.AmbientStrength = app.Backup.AmbientStrength;
            app.Primitive.DiffuseStrength = app.Backup.DiffuseStrength;
            app.Primitive.SpecularStrength = app.Backup.SpecularStrength;
            app.Primitive.SpecularExponent = app.Backup.SpecularExponent;

            delete(app);
        end

        % Callback function: not associated with a component
        function OKButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Material';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ColorColorPickerLabel = uilabel(app.GridLayout);
            app.ColorColorPickerLabel.Text = 'Color';
            app.ColorColorPicker = uicolorpicker(app.GridLayout);
            app.ColorColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorColorPickerValueChanged, true);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AmbientStrengthSliderLabel = uilabel(app.GridLayout);
            app.AmbientStrengthSliderLabel.Text = 'AmbientStrength';
            app.AmbientStrengthSlider = uislider(app.GridLayout);
            app.AmbientStrengthSlider.Limits = [0 1];
            app.AmbientStrengthSlider.MajorTicks = [0 1];
            app.AmbientStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @AmbientStrengthSpinnerValueChanged, true);
            app.AmbientStrengthSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AmbientStrengthSlider.Step = 0.05;
            app.DiffuseStrengthSliderLabel = uilabel(app.GridLayout);
            app.DiffuseStrengthSliderLabel.Text = 'DiffuseStrength';
            app.DiffuseStrengthSlider = uislider(app.GridLayout);
            app.DiffuseStrengthSlider.Limits = [0 1];
            app.DiffuseStrengthSlider.MajorTicks = [0 1];
            app.DiffuseStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @DiffuseStrengthSpinnerValueChanged, true);
            app.DiffuseStrengthSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DiffuseStrengthSlider.Step = 0.05;
            app.SpecularStrengthSliderLabel = uilabel(app.GridLayout);
            app.SpecularStrengthSliderLabel.Text = 'SpecularStrength';
            app.SpecularStrengthSlider = uislider(app.GridLayout);
            app.SpecularStrengthSlider.Limits = [0 1];
            app.SpecularStrengthSlider.MajorTicks = [0 1];
            app.SpecularStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @SpecularStrengthSpinnerValueChanged, true);
            app.SpecularStrengthSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SpecularStrengthSlider.Step = 0.05;
            app.SpecularExponentSliderLabel = uilabel(app.GridLayout);
            app.SpecularExponentSliderLabel.Text = 'SpecularExponent';
            app.SpecularExponentSlider = uislider(app.GridLayout);
            app.SpecularExponentSlider.Limits = [1 20];
            app.SpecularExponentSlider.MajorTicks = [1 20];
            app.SpecularExponentSlider.ValueChangingFcn = createCallbackFcn(app, @SpecularExponentSpinnerValueChanged, true);
            app.SpecularExponentSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.SpecularExponentSlider.Step = 1;
            app.SpecularExponentSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaterialApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end